classdef defects < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        Voltage_800Slider        matlab.ui.control.Slider
        Voltage_800SliderLabel   matlab.ui.control.Label
        Power_800Slider          matlab.ui.control.Slider
        Power_800SliderLabel     matlab.ui.control.Label
        CheckBox_10              matlab.ui.control.CheckBox
        CheckBox_9               matlab.ui.control.CheckBox
        CheckBox_8               matlab.ui.control.CheckBox
        CheckBox_7               matlab.ui.control.CheckBox
        CheckBox6                matlab.ui.control.CheckBox
        CheckBox5                matlab.ui.control.CheckBox
        CheckBox_12              matlab.ui.control.CheckBox
        CheckBox_11              matlab.ui.control.CheckBox
        CheckBox_6               matlab.ui.control.CheckBox
        Voltage_500SliderLabel   matlab.ui.control.Label
        CheckBox_5               matlab.ui.control.CheckBox
        Voltage_700Slider        matlab.ui.control.Slider
        Voltage_700SliderLabel   matlab.ui.control.Label
        Voltage_600Slider        matlab.ui.control.Slider
        Voltage_600SliderLabel   matlab.ui.control.Label
        Power_700Slider          matlab.ui.control.Slider
        Power_700SliderLabel     matlab.ui.control.Label
        Power_600Slider          matlab.ui.control.Slider
        Power_600SliderLabel     matlab.ui.control.Label
        Voltage_500Slider        matlab.ui.control.Slider
        Voltage__400Slider       matlab.ui.control.Slider
        Voltage__400SliderLabel  matlab.ui.control.Label
        Voltage_300Slider        matlab.ui.control.Slider
        Voltage_300SliderLabel   matlab.ui.control.Label
        Voltage_200Slider        matlab.ui.control.Slider
        Voltage_200SliderLabel   matlab.ui.control.Label
        Power_500Slider          matlab.ui.control.Slider
        Power_500SliderLabel     matlab.ui.control.Label
        Power_400Slider          matlab.ui.control.Slider
        Power_400SliderLabel     matlab.ui.control.Label
        Power_300Slider          matlab.ui.control.Slider
        Power_300SliderLabel     matlab.ui.control.Label
        Power_200Slider          matlab.ui.control.Slider
        Power_200SliderLabel     matlab.ui.control.Label
        EditField7               matlab.ui.control.NumericEditField
        EditField6               matlab.ui.control.NumericEditField
        EditField5               matlab.ui.control.NumericEditField
        EditField4               matlab.ui.control.NumericEditField
        EditField3               matlab.ui.control.NumericEditField
        EditField2               matlab.ui.control.NumericEditField
        EditField                matlab.ui.control.NumericEditField
        CheckBox_4               matlab.ui.control.CheckBox
        CheckBox_3               matlab.ui.control.CheckBox
        CheckBox_2               matlab.ui.control.CheckBox
        CheckBox_1               matlab.ui.control.CheckBox
        UIAxes_7                 matlab.ui.control.UIAxes
        UIAxes_6                 matlab.ui.control.UIAxes
        UIAxes_5                 matlab.ui.control.UIAxes
        UIAxes_4                 matlab.ui.control.UIAxes
        UIAxes_3                 matlab.ui.control.UIAxes
        UIAxes_2                 matlab.ui.control.UIAxes
        UIAxes                   matlab.ui.control.UIAxes
    end


    properties (Access = public)
        %bools for plotting correctly
        Slider1Enabled logical = false 
        Slider5Enabled logical = false

        Slider2Enabled logical = false 
        Slider6Enabled logical = false

        Slider3Enabled logical = false 
        Slider7Enabled logical = false

        Slider4Enabled logical = false 
        Slider8Enabled logical = false

        Slider9Enabled logical = false 
        Slider12Enabled logical = false

        Slider10Enabled logical = false 
        Slider13Enabled logical = false

        Slider14Enabled logical = false 
        Slider15Enabled logical = false
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIAxes.XLim = [1 10];
            app.UIAxes.YLim = [1 5];
            app.UIAxes_2.XLim = [1 10];
            app.UIAxes_2.YLim = [1 5];
            app.UIAxes_3.XLim = [1 10];
            app.UIAxes_3.YLim = [1 5];
            app.UIAxes_4.XLim = [1 10];
            app.UIAxes_4.YLim = [1 5];
            app.UIAxes_5.XLim = [1 10];
            app.UIAxes_5.YLim = [1 5];
            app.UIAxes_6.XLim = [1 10];
            app.UIAxes_6.YLim = [1 5];
            app.UIAxes_7.XLim = [1 10];
            app.UIAxes_7.YLim = [1 5];

            set(app.UIAxes, 'YDir', 'reverse');
            set(app.UIAxes_2, 'YDir', 'reverse');
            set(app.UIAxes_3, 'YDir', 'reverse');
            set(app.UIAxes_4, 'YDir', 'reverse');
            set(app.UIAxes_5, 'YDir', 'reverse');
            set(app.UIAxes_6, 'YDir', 'reverse');
            set(app.UIAxes_7, 'YDir', 'reverse');
    
            
            run('TwoDefectData.m');
            min_power200 = min(data200(:,5));
            max_power200 = max(data200(:,5));
            app.Power_200Slider.Limits = [min_power200 max_power200];

            min_voltage200 = min(v_data200(:,5));
            max_voltage200 = max(v_data200(:,5));
            app.Voltage_200Slider.Limits = [min_voltage200 max_voltage200];

            min_power300 = min(data300(:,5));
            max_power300 = max(data300(:,5));
            app.Power_300Slider.Limits = [min_power300 max_power300];

            min_voltage300 = min(v_data300(:,5));
            max_voltage300 = max(v_data300(:,5));
            app.Voltage_300Slider.Limits = [min_voltage300 max_voltage300];

            min_power400 = min(data400(:,5));
            max_power400 = max(data400(:,5));
            app.Power_400Slider.Limits = [min_power400 max_power400];

            min_voltage400 = min(v_data400(:,5));
            max_voltage400 = max(v_data400(:,5));
            app.Voltage__400Slider.Limits = [min_voltage400 max_voltage400];

            min_power500 = min(data500(:,5));
            max_power500 = max(data500(:,5));
            app.Power_500Slider.Limits = [min_power500 max_power500];

            min_voltage500 = min(v_data500(:,5));
            max_voltage500 = max(v_data500(:,5));
            app.Voltage_500Slider.Limits = [min_voltage500 max_voltage500];

            min_power600 = min(data600(:,5));
            max_power600 = max(data600(:,5));
            app.Power_600Slider.Limits = [min_power600 max_power600];

            min_voltage600 = min(v_data600(:,5));
            max_voltage600 = max(v_data600(:,5));
            app.Voltage_600Slider.Limits = [min_voltage600 max_voltage600];

            min_power700 = min(data700(:,5));
            max_power700 = max(data700(:,5));
            app.Power_700Slider.Limits = [min_power700 max_power700];

            min_voltage700 = min(v_data700(:,5));
            max_voltage700 = max(v_data700(:,5));
            app.Voltage_700Slider.Limits = [min_voltage700 max_voltage700];

            min_power800 = min(data800(:,5));
            max_power800 = max(data800(:,5));
            app.Power_800Slider.Limits = [min_power800 max_power800];

            min_voltage800 = min(v_data800(:,5));
            max_voltage800 = max(v_data800(:,5));
            app.Voltage_800Slider.Limits = [min_voltage800 max_voltage800];
        end

        % Callback function
        function SliderValueChanged(app, event)

        end

        % Callback function
        function Slider_2ValueChanged(app, event)
         
        end

        % Callback function
        function Slider_5ValueChanged(app, event)
            
        end

        % Callback function
        function Slider_7ValueChanged(app, event)

        end

        % Callback function
        function Slider_3ValueChanged(app, event)
  
        end

        % Callback function
        function Slider_6ValueChanged(app, event)

        end

        % Callback function
        function Slider_4ValueChanged(app, event)
 
        end

        % Button down function: UIAxes_4
        function UIAxes_4ButtonDown(app, event)
            
        end

        % Button down function: UIAxes_5
        function UIAxes_5ButtonDown(app, event)
            
        end

        % Value changed function: Power_300Slider
        function Power_300SliderValueChanged(app, event)
            selected_power = app.Power_300Slider.Value;
            app.EditField2.Value = selected_power;
            title(app.UIAxes_2, '300A (µV / µΩ)')

            if app.Slider2Enabled
                run('TwoDefectData.m');
    
                cla(app.UIAxes_2, 'reset');
                hold(app.UIAxes_2, 'on');
                app.UIAxes_2.XLim = [1 10];
                app.UIAxes_2.YLim = [1 5];
                set(app.UIAxes_2, 'YDir', 'reverse');
                
    
                % unique values we'll round to
                unique_power = unique(data300(:,5));
                nearest_power = unique_power(find(abs(unique_power - selected_power) == min(abs(unique_power - selected_power)), 1));
                
                find_defect = data300(data300(:,5) == nearest_power, :);
    
                
                %plot first pair 
                scatter(app.UIAxes_2, find_defect(1,2), find_defect(1,1), 'filled', 'c');
                scatter(app.UIAxes_2, find_defect(1,4), find_defect(1,3), 'filled', 'c');

                %plot second if it exists
                sz = size(find_defect);
                if sz > 1
                    scatter(app.UIAxes_2, find_defect(2,2), find_defect(2,1), 'filled', 'm');
                    scatter(app.UIAxes_2, find_defect(2,4), find_defect(2,3), 'filled', 'm');
                end
            end
        end

        % Value changed function: Power_400Slider
        function Power_400SliderValueChanged(app, event)
            selected_power = app.Power_400Slider.Value;
            app.EditField3.Value = selected_power;
            title(app.UIAxes_5, '400A (µV / µΩ)')

            if app.Slider3Enabled
                run('TwoDefectData.m');
    
                cla(app.UIAxes_5, 'reset');
                hold(app.UIAxes_5, 'on');
                app.UIAxes_5.XLim = [1 10];
                app.UIAxes_5.YLim = [1 5];
                set(app.UIAxes_5, 'YDir', 'reverse');
                
    
                % unique values we'll round to
                unique_power = unique(data400(:,5));
                nearest_power = unique_power(find(abs(unique_power - selected_power) == min(abs(unique_power - selected_power)), 1));
                
                find_defect = data400(data400(:,5) == nearest_power, :);
    
                
                %plot first pair 
                scatter(app.UIAxes_5, find_defect(1,2), find_defect(1,1), 'filled', 'c');
                scatter(app.UIAxes_5, find_defect(1,4), find_defect(1,3), 'filled', 'c');

                %plot second if it exists
                sz = size(find_defect);
                if sz > 1
                    scatter(app.UIAxes_5, find_defect(2,2), find_defect(2,1), 'filled', 'm');
                    scatter(app.UIAxes_5, find_defect(2,4), find_defect(2,3), 'filled', 'm');
                end
            end
            

        end

        % Value changed function: Power_500Slider
        function Power_500SliderValueChanged(app, event)
            selected_power = app.Power_500Slider.Value;
            app.EditField4.Value = selected_power;
            title(app.UIAxes_7, '500A (µV / µΩ)')

            if app.Slider4Enabled
                run('TwoDefectData.m');
    
                cla(app.UIAxes_7, 'reset');
                hold(app.UIAxes_7, 'on');
                app.UIAxes_7.XLim = [1 10];
                app.UIAxes_7.YLim = [1 5];
                set(app.UIAxes_7, 'YDir', 'reverse');
                
    
                % unique values we'll round to
                unique_power = unique(data500(:,5));
                nearest_power = unique_power(find(abs(unique_power - selected_power) == min(abs(unique_power - selected_power)), 1));
                
                find_defect = data500(data500(:,5) == nearest_power, :);
    
                
                %plot first pair 
                scatter(app.UIAxes_7, find_defect(1,2), find_defect(1,1), 'filled', 'c');
                scatter(app.UIAxes_7, find_defect(1,4), find_defect(1,3), 'filled', 'c');

                %plot second if it exists
                sz = size(find_defect);
                if sz > 1
                    scatter(app.UIAxes_7, find_defect(2,2), find_defect(2,1), 'filled', 'm');
                    scatter(app.UIAxes_7, find_defect(2,4), find_defect(2,3), 'filled', 'm');
                end
            end
        end

        % Value changed function: Power_600Slider
        function Power_600SliderValueChanged2(app, event)
            
            selected_power = app.Power_600Slider.Value;
            app.EditField5.Value = selected_power;
            title(app.UIAxes_3, '600A (µV / µΩ)')

            if app.Slider9Enabled
                run('TwoDefectData.m');
    
                cla(app.UIAxes_3, 'reset');
                hold(app.UIAxes_3, 'on');
                app.UIAxes_3.XLim = [1 10];
                app.UIAxes_3.YLim = [1 5];
                set(app.UIAxes_3, 'YDir', 'reverse');
                
    
                % unique values we'll round to
                unique_power = unique(data600(:,5));
                nearest_power = unique_power(find(abs(unique_power - selected_power) == min(abs(unique_power - selected_power)), 1));
                
                find_defect = data600(data600(:,5) == nearest_power, :);
    
                
                %plot first pair 
                scatter(app.UIAxes_3, find_defect(1,2), find_defect(1,1), 'filled', 'c');
                scatter(app.UIAxes_3, find_defect(1,4), find_defect(1,3), 'filled', 'c');

                %plot second if it exists
                sz = size(find_defect);
                if sz > 1
                    scatter(app.UIAxes_3, find_defect(2,2), find_defect(2,1), 'filled', 'm');
                    scatter(app.UIAxes_3, find_defect(2,4), find_defect(2,3), 'filled', 'm');
                end      
            end  
        end

        % Value changed function: Power_700Slider
        function Power_700SliderValueChanged(app, event)
            selected_power = app.Power_700Slider.Value;
            app.EditField6.Value = selected_power;
            title(app.UIAxes_4, '700A (µV / µΩ)')

            if app.Slider10Enabled
                run('TwoDefectData.m');
    
                cla(app.UIAxes_4, 'reset');
                hold(app.UIAxes_4, 'on');
                app.UIAxes_4.XLim = [1 10];
                app.UIAxes_4.YLim = [1 5];
                set(app.UIAxes_4, 'YDir', 'reverse');
                
    
                % unique values we'll round to
                unique_power = unique(data700(:,5));
                nearest_power = unique_power(find(abs(unique_power - selected_power) == min(abs(unique_power - selected_power)), 1));
                
                find_defect = data700(data700(:,5) == nearest_power, :);
    
                
                %plot first pair 
                scatter(app.UIAxes_4, find_defect(1,2), find_defect(1,1), 'filled', 'c');
                scatter(app.UIAxes_4, find_defect(1,4), find_defect(1,3), 'filled', 'c');

                %plot second if it exists
                sz = size(find_defect);
                if sz > 1
                    scatter(app.UIAxes_4, find_defect(2,2), find_defect(2,1), 'filled', 'm');
                    scatter(app.UIAxes_4, find_defect(2,4), find_defect(2,3), 'filled', 'm');
                end
            end
        end

        % Value changed function: Power_800Slider
        function Power_800SliderValueChanged(app, event)
            selected_power = app.Power_800Slider.Value;
            app.EditField7.Value = selected_power;
            title(app.UIAxes_6, '800A (µV / µΩ)')
            
            if app.Slider14Enabled
                run('TwoDefectData.m');
    
                cla(app.UIAxes_6, 'reset');
                hold(app.UIAxes_6, 'on');
                app.UIAxes_6.XLim = [1 10];
                app.UIAxes_6.YLim = [1 5];
                set(app.UIAxes_6, 'YDir', 'reverse');
                
    
                % unique values we'll round to
                unique_power = unique(data800(:,5));
                nearest_power = unique_power(find(abs(unique_power - selected_power) == min(abs(unique_power - selected_power)), 1));
                
                find_defect = data800(data800(:,5) == nearest_power, :);
    
                
                %plot first pair 
                scatter(app.UIAxes_6, find_defect(1,2), find_defect(1,1), 'filled', 'c');
                scatter(app.UIAxes_6, find_defect(1,4), find_defect(1,3), 'filled', 'c');

                %plot second if it exists
                sz = size(find_defect);
                if sz > 1
                    scatter(app.UIAxes_6, find_defect(2,2), find_defect(2,1), 'filled', 'm');
                    scatter(app.UIAxes_6, find_defect(2,4), find_defect(2,3), 'filled', 'm');
                end
            end
        end

        % Value changed function: CheckBox_1
        function CheckBox_1ValueChanged(app, event)
            app.Slider1Enabled = app.CheckBox_1.Value; %for use in other callback functions

            %enable corresponding sliders
            if app.Slider1Enabled
                app.Power_200Slider.Enable = 'on';
            else
                app.Power_200Slider.Enable = 'off';
            end
            
   
        end

        % Value changed function: CheckBox_2
        function CheckBox_2ValueChanged(app, event)
            app.Slider5Enabled = app.CheckBox_2.Value;

            %enable corresponding sliders
            if app.Slider5Enabled
                app.Voltage_200Slider.Enable = 'on';
            else
                app.Voltage_200Slider.Enable = 'off';
            end
            
        end

        % Value changed function: CheckBox_5
        function CheckBox_5ValueChanged(app, event)
            app.Slider2Enabled = app.CheckBox_5.Value; %for use in other callback functions

            %enable corresponding sliders
            if app.Slider2Enabled
                app.Power_300Slider.Enable = 'on';
            else
                app.Power_300Slider.Enable = 'off';
            end 
            
            
        end

        % Value changed function: CheckBox_6
        function CheckBox_6ValueChanged(app, event)
            app.Slider6Enabled = app.CheckBox_6.Value;

            %enable corresponding sliders
            if app.Slider6Enabled
                app.Voltage_300Slider.Enable = 'on';
            else
                app.Voltage_300Slider.Enable = 'off';
            end
            
        end

        % Value changed function: CheckBox_11
        function CheckBox_11ValueChanged(app, event)
            app.Slider3Enabled = app.CheckBox_11.Value; %for use in other callback functions

            %enable corresponding sliders
            if app.Slider3Enabled
                app.Power_400Slider.Enable = 'on';
            else
                app.Power_400Slider.Enable = 'off';
            end     
        end

        % Value changed function: CheckBox_12
        function CheckBox_12ValueChanged(app, event)
            app.Slider7Enabled = app.CheckBox_12.Value; %for use in other callback functions

            %enable corresponding sliders
            if app.Slider7Enabled
                app.Voltage__400Slider.Enable = 'on';
            else
                app.Voltage__400Slider.Enable = 'off';
            end             
        end

        % Value changed function: CheckBox5
        function CheckBox5ValueChanged(app, event)
            app.Slider4Enabled = app.CheckBox5.Value; %for use in other callback functions

            %enable corresponding sliders
            if app.Slider4Enabled
                app.Power_500Slider.Enable = 'on';
            else
                app.Power_500Slider.Enable = 'off';
            end
            
        end

        % Value changed function: CheckBox6
        function CheckBox6ValueChanged(app, event)
            app.Slider8Enabled = app.CheckBox6.Value; %for use in other callback functions

            %enable corresponding sliders
            if app.Slider8Enabled
                app.Voltage_500Slider.Enable = 'on';
            else
                app.Voltage_500Slider.Enable = 'off';
            end            
        end

        % Value changed function: CheckBox_3
        function CheckBox_3ValueChanged(app, event)
            app.Slider9Enabled = app.CheckBox_3.Value; %for use in other callback functions

            %enable corresponding sliders
            if app.Slider9Enabled
                app.Power_600Slider.Enable = 'on';
            else
                app.Power_600Slider.Enable = 'off';
            end
            
        end

        % Value changed function: CheckBox_4
        function CheckBox_4ValueChanged(app, event)
            app.Slider12Enabled = app.CheckBox_4.Value; %for use in other callback functions

            %enable corresponding sliders
            if app.Slider12Enabled
                app.Voltage_600Slider.Enable = 'on';
            else
                app.Voltage_600Slider.Enable = 'off';
            end
            
        end

        % Value changed function: CheckBox_7
        function CheckBox_7ValueChanged(app, event)
            app.Slider10Enabled = app.CheckBox_7.Value; %for use in other callback functions

            %enable corresponding sliders
            if app.Slider10Enabled
                app.Power_700Slider.Enable = 'on';
            else
                app.Power_700Slider.Enable = 'off';
            end
            
        end

        % Value changed function: CheckBox_8
        function CheckBox_8ValueChanged(app, event)
            app.Slider13Enabled = app.CheckBox_8.Value; %for use in other callback functions

            %enable corresponding sliders
            if app.Slider13Enabled
                app.Voltage_700Slider.Enable = 'on';
            else
                app.Voltage_700Slider.Enable = 'off';
            end            
        end

        % Value changed function: CheckBox_9
        function CheckBox_9ValueChanged(app, event)
            app.Slider14Enabled = app.CheckBox_9.Value; %for use in other callback functions

            %enable corresponding sliders
            if app.Slider14Enabled
                app.Power_800Slider.Enable = 'on';
            else
                app.Power_800Slider.Enable = 'off';
            end
            
        end

        % Value changed function: CheckBox_10
        function CheckBox_10ValueChanged(app, event)
            app.Slider15Enabled = app.CheckBox_10.Value; %for use in other callback functions

            %enable corresponding sliders
            if app.Slider15Enabled
                app.Voltage_800Slider.Enable = 'on';
            else
                app.Voltage_800Slider.Enable = 'off';
            end
            
        end

        % Value changed function: Power_200Slider
        function Power_200SliderValueChanged(app, event)

            selected_power = app.Power_200Slider.Value;
            app.EditField.Value = selected_power;
            title(app.UIAxes, '200A (µV / µΩ)')

            if app.Slider1Enabled
                run('TwoDefectData.m');
    
                cla(app.UIAxes, 'reset');
                hold(app.UIAxes, 'on');
                app.UIAxes.XLim = [1 10];
                app.UIAxes.YLim = [1 5];
                set(app.UIAxes, 'YDir', 'reverse');
                
    
                % unique values we'll round to
                unique_power = unique(data200(:,5));
                nearest_power = unique_power(find(abs(unique_power - selected_power) == min(abs(unique_power - selected_power)), 1));
                
                find_defect = data200(data200(:,5) == nearest_power, :);
    
                
                %plot first pair 
                scatter(app.UIAxes, find_defect(1,2), find_defect(1,1), 'filled', 'c');
                scatter(app.UIAxes, find_defect(1,4), find_defect(1,3), 'filled', 'c');

                %plot second if it exists
                sz = size(find_defect);
                if sz > 1
                    scatter(app.UIAxes, find_defect(2,2), find_defect(2,1), 'filled', 'm');
                    scatter(app.UIAxes, find_defect(2,4), find_defect(2,3), 'filled', 'm');
                end
            end            
        end

        % Value changed function: Voltage_300Slider
        function Voltage_300SliderValueChanged(app, event)

            selected_voltage = app.Voltage_300Slider.Value;
            app.EditField2.Value = selected_voltage;
            title(app.UIAxes_2, '300A (µV / µΩ)')

            if app.Slider6Enabled
                run('TwoDefectData.m');
    
                cla(app.UIAxes_2, 'reset');
                hold(app.UIAxes_2, 'on');
                app.UIAxes_2.XLim = [1 10];
                app.UIAxes_2.YLim = [1 5];
                set(app.UIAxes_2, 'YDir', 'reverse');
                
    
                % unique values we'll round to
                unique_voltage = unique(v_data300(:,5));
                nearest_voltage = unique_voltage(find(abs(unique_voltage - selected_voltage) == min(abs(unique_voltage - selected_voltage)), 1));
                
                find_defect = v_data300(v_data300(:,5) == nearest_voltage, :);
    
                %plot first pair 
                scatter(app.UIAxes_2, find_defect(1,2), find_defect(1,1), 'filled', 'c');
                scatter(app.UIAxes_2, find_defect(1,4), find_defect(1,3), 'filled', 'c');

                %plot second if it exists
                sz = size(find_defect);
                if sz > 1
                    scatter(app.UIAxes_2, find_defect(2,2), find_defect(2,1), 'filled', 'm');
                    scatter(app.UIAxes_2, find_defect(2,4), find_defect(2,3), 'filled', 'm');
                end
            end            
            
        end

        % Value changed function: Voltage__400Slider
        function Voltage__400SliderValueChanged(app, event)
            selected_voltage = app.Voltage__400Slider.Value;
            app.EditField3.Value = selected_voltage;
            title(app.UIAxes_5, '400A (µV / µΩ)')

            if app.Slider7Enabled
                run('TwoDefectData.m');
    
                cla(app.UIAxes_5, 'reset');
                hold(app.UIAxes_5, 'on');
                app.UIAxes_5.XLim = [1 10];
                app.UIAxes_5.YLim = [1 5];
                set(app.UIAxes_5, 'YDir', 'reverse');
                
    
                % unique values we'll round to
                unique_voltage = unique(v_data400(:,5));
                nearest_voltage = unique_voltage(find(abs(unique_voltage - selected_voltage) == min(abs(unique_voltage - selected_voltage)), 1));
                
                find_defect = v_data400(v_data400(:,5) == nearest_voltage, :);
    
                %plot first pair 
                scatter(app.UIAxes_5, find_defect(1,2), find_defect(1,1), 'filled', 'c');
                scatter(app.UIAxes_5, find_defect(1,4), find_defect(1,3), 'filled', 'c');

                %plot second if it exists
                sz = size(find_defect);
                if sz > 1
                    scatter(app.UIAxes_5, find_defect(2,2), find_defect(2,1), 'filled', 'm');
                    scatter(app.UIAxes_5, find_defect(2,4), find_defect(2,3), 'filled', 'm');
                end
            end            
        end

        % Value changed function: Voltage_500Slider
        function Voltage_500SliderValueChanged(app, event)
            selected_voltage = app.Voltage_500Slider.Value;
            app.EditField4.Value = selected_voltage;
            title(app.UIAxes_7, '500A (µV / µΩ)')

            if app.Slider8Enabled
                run('TwoDefectData.m');
    
                cla(app.UIAxes_7, 'reset');
                hold(app.UIAxes_7, 'on');
                app.UIAxes_7.XLim = [1 10];
                app.UIAxes_7.YLim = [1 5];
                set(app.UIAxes_7, 'YDir', 'reverse');
                
    
                % unique values we'll round to
                unique_voltage = unique(v_data500(:,5));
                nearest_voltage = unique_voltage(find(abs(unique_voltage - selected_voltage) == min(abs(unique_voltage - selected_voltage)), 1));
                
                find_defect = v_data500(v_data500(:,5) == nearest_voltage, :);
    
                
                %plot first pair 
                scatter(app.UIAxes_7, find_defect(1,2), find_defect(1,1), 'filled', 'c');
                scatter(app.UIAxes_7, find_defect(1,4), find_defect(1,3), 'filled', 'c');

                %plot second if it exists
                sz = size(find_defect);
                if sz > 1
                    scatter(app.UIAxes_7, find_defect(2,2), find_defect(2,1), 'filled', 'm');
                    scatter(app.UIAxes_7, find_defect(2,4), find_defect(2,3), 'filled', 'm');
                end
            end                
        end

        % Value changed function: Voltage_600Slider
        function Voltage_600SliderValueChanged(app, event)
            selected_voltage = app.Voltage_600Slider.Value;
            app.EditField5.Value = selected_voltage;
            title(app.UIAxes_3, '600A (µV / µΩ)')

            if app.Slider12Enabled
                run('TwoDefectData.m');
    
                cla(app.UIAxes_3, 'reset');
                hold(app.UIAxes_3, 'on');
                app.UIAxes_3.XLim = [1 10];
                app.UIAxes_3.YLim = [1 5];
                set(app.UIAxes_3, 'YDir', 'reverse');
                
    
                % unique values we'll round to
                unique_voltage = unique(v_data600(:,5));
                nearest_voltage = unique_voltage(find(abs(unique_voltage - selected_voltage) == min(abs(unique_voltage - selected_voltage)), 1));
                
                find_defect = v_data600(v_data600(:,5) == nearest_voltage, :);
    
                
                %plot first pair 
                scatter(app.UIAxes_3, find_defect(1,2), find_defect(1,1), 'filled', 'c');
                scatter(app.UIAxes_3, find_defect(1,4), find_defect(1,3), 'filled', 'c');

                %plot second if it exists
                sz = size(find_defect);
                if sz > 1
                    scatter(app.UIAxes_3, find_defect(2,2), find_defect(2,1), 'filled', 'm');
                    scatter(app.UIAxes_3, find_defect(2,4), find_defect(2,3), 'filled', 'm');
                end
            end             
        end

        % Value changed function: Voltage_700Slider
        function Voltage_700SliderValueChanged(app, event)
            selected_voltage = app.Voltage_700Slider.Value;
            app.EditField6.Value = selected_voltage;
            title(app.UIAxes_4, '700A (µV / µΩ)')

            if app.Slider13Enabled
                run('TwoDefectData.m');
    
                cla(app.UIAxes_4, 'reset');
                hold(app.UIAxes_4, 'on');
                app.UIAxes_4.XLim = [1 10];
                app.UIAxes_4.YLim = [1 5];
                set(app.UIAxes_4, 'YDir', 'reverse');
                
   
                % unique values we'll round to
                unique_voltage = unique(v_data700(:,5));
                nearest_voltage = unique_voltage(find(abs(unique_voltage - selected_voltage) == min(abs(unique_voltage - selected_voltage)), 1));
                
                find_defect = v_data700(v_data700(:,5) == nearest_voltage, :);
    
                
                %plot first pair 
                scatter(app.UIAxes_4, find_defect(1,2), find_defect(1,1), 'filled', 'c');
                scatter(app.UIAxes_4, find_defect(1,4), find_defect(1,3), 'filled', 'c');

                %plot second if it exists
                sz = size(find_defect);
                if sz > 1
                    scatter(app.UIAxes_4, find_defect(2,2), find_defect(2,1), 'filled', 'm');
                    scatter(app.UIAxes_4, find_defect(2,4), find_defect(2,3), 'filled', 'm');
                end
            end              
        end

        % Value changed function: Voltage_800Slider
        function Voltage_800SliderValueChanged(app, event)
            selected_voltage = app.Voltage_800Slider.Value;
            app.EditField7.Value = selected_voltage;
            title(app.UIAxes_6, '800A (µV / µΩ)')

            if app.Slider15Enabled
                run('TwoDefectData.m');
    
                cla(app.UIAxes_6, 'reset');
                hold(app.UIAxes_6, 'on');
                app.UIAxes_6.XLim = [1 10];
                app.UIAxes_6.YLim = [1 5];
                set(app.UIAxes_6, 'YDir', 'reverse');
                
    
                % unique values we'll round to
                unique_voltage = unique(v_data800(:,5));
                nearest_voltage = unique_voltage(find(abs(unique_voltage - selected_voltage) == min(abs(unique_voltage - selected_voltage)), 1));
                
                find_defect = v_data800(v_data800(:,5) == nearest_voltage, :);
    
                %plot first pair 
                scatter(app.UIAxes_6, find_defect(1,2), find_defect(1,1), 'filled', 'c');
                scatter(app.UIAxes_6, find_defect(1,4), find_defect(1,3), 'filled', 'c');

                %plot second if it exists
                sz = size(find_defect);
                if sz > 1
                    scatter(app.UIAxes_6, find_defect(2,2), find_defect(2,1), 'filled', 'm');
                    scatter(app.UIAxes_6, find_defect(2,4), find_defect(2,3), 'filled', 'm');
                end
            end              
        end

        % Value changed function: Voltage_200Slider
        function Voltage_200SliderValueChanged(app, event)

            selected_voltage = app.Voltage_200Slider.Value;
            app.EditField.Value = selected_voltage;
            title(app.UIAxes, '200A (µV / µΩ)')

            if app.Slider5Enabled
                run('TwoDefectData.m');
    
                cla(app.UIAxes, 'reset');
                hold(app.UIAxes, 'on');
                app.UIAxes.XLim = [1 10];
                app.UIAxes.YLim = [1 5];
                set(app.UIAxes, 'YDir', 'reverse');
                
    
                % unique values we'll round to
                unique_voltage = unique(v_data200(:,5));
                nearest_voltage = unique_voltage(find(abs(unique_voltage - selected_voltage) == min(abs(unique_voltage - selected_voltage)), 1));
                
                find_defect = v_data200(v_data200(:,5) == nearest_voltage, :);
    
                %plot first pair 
                scatter(app.UIAxes, find_defect(1,2), find_defect(1,1), 'filled', 'c');
                scatter(app.UIAxes, find_defect(1,4), find_defect(1,3), 'filled', 'c');

                %plot second if it exists
                sz = size(find_defect);
                if sz > 1
                    scatter(app.UIAxes, find_defect(2,2), find_defect(2,1), 'filled', 'm');
                    scatter(app.UIAxes, find_defect(2,4), find_defect(2,3), 'filled', 'm');
                end
            end                        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, '200A')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [1 2 3 4 5 6 7 8 9 10];
            app.UIAxes.XTickLabel = {'1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'};
            app.UIAxes.YTick = [1 2 3 4 5];
            app.UIAxes.YTickLabel = {'1'; '2'; '3'; '4'; '5'};
            app.UIAxes_2 = uiaxes(app.GridLayout);
            title(app.UIAxes_2, '300A')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.XTick = [1 2 3 4 5 6 7 8 9 10];
            app.UIAxes_2.XTickLabel = {'1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'};
            app.UIAxes_2.YTick = [1 2 3 4 5];
            app.UIAxes_2.YTickLabel = {'1'; '2'; '3'; '4'; '5'};
            app.UIAxes_3 = uiaxes(app.GridLayout);
            title(app.UIAxes_3, '600A')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_3.XTick = [1 2 3 4 5 6 7 8 9 10];
            app.UIAxes_3.XTickLabel = {'1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'};
            app.UIAxes_3.YTick = [1 2 3 4 5];
            app.UIAxes_3.YTickLabel = {'1'; '2'; '3'; '4'; '5'};
            app.UIAxes_4 = uiaxes(app.GridLayout);
            title(app.UIAxes_4, '700A')
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_4.XTick = [1 2 3 4 5 6 7 8 9 10];
            app.UIAxes_4.XTickLabel = {'1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'};
            app.UIAxes_4.YTick = [1 2 3 4 5];
            app.UIAxes_4.YTickLabel = {'1'; '2'; '3'; '4'; '5'};
            app.UIAxes_4.ButtonDownFcn = createCallbackFcn(app, @UIAxes_4ButtonDown, true);
            app.UIAxes_5 = uiaxes(app.GridLayout);
            title(app.UIAxes_5, '400A')
            xlabel(app.UIAxes_5, 'X')
            ylabel(app.UIAxes_5, 'Y')
            zlabel(app.UIAxes_5, 'Z')
            app.UIAxes_5.XTick = [1 2 3 4 5 6 7 8 9 10];
            app.UIAxes_5.XTickLabel = {'1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'};
            app.UIAxes_5.YTick = [1 2 3 4 5];
            app.UIAxes_5.YTickLabel = {'1'; '2'; '3'; '4'; '5'};
            app.UIAxes_5.ButtonDownFcn = createCallbackFcn(app, @UIAxes_5ButtonDown, true);
            app.UIAxes_6 = uiaxes(app.GridLayout);
            title(app.UIAxes_6, '800A')
            xlabel(app.UIAxes_6, 'X')
            ylabel(app.UIAxes_6, 'Y')
            zlabel(app.UIAxes_6, 'Z')
            app.UIAxes_6.XTick = [1 2 3 4 5 6 7 8 9 10];
            app.UIAxes_6.XTickLabel = {'1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'};
            app.UIAxes_6.YTick = [1 2 3 4 5];
            app.UIAxes_6.YTickLabel = {'1'; '2'; '3'; '4'; '5'};
            app.UIAxes_7 = uiaxes(app.GridLayout);
            title(app.UIAxes_7, '500A')
            xlabel(app.UIAxes_7, 'X')
            ylabel(app.UIAxes_7, 'Y')
            zlabel(app.UIAxes_7, 'Z')
            app.UIAxes_7.XTick = [1 2 3 4 5 6 7 8 9 10];
            app.UIAxes_7.XTickLabel = {'1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'};
            app.UIAxes_7.YTick = [1 2 3 4 5];
            app.UIAxes_7.YTickLabel = {'1'; '2'; '3'; '4'; '5'};
            app.CheckBox_1 = uicheckbox(app.GridLayout);
            app.CheckBox_1.ValueChangedFcn = createCallbackFcn(app, @CheckBox_1ValueChanged, true);
            app.CheckBox_1.Text = '';
            app.CheckBox_2 = uicheckbox(app.GridLayout);
            app.CheckBox_2.ValueChangedFcn = createCallbackFcn(app, @CheckBox_2ValueChanged, true);
            app.CheckBox_2.Text = '';
            app.CheckBox_3 = uicheckbox(app.GridLayout);
            app.CheckBox_3.ValueChangedFcn = createCallbackFcn(app, @CheckBox_3ValueChanged, true);
            app.CheckBox_3.Text = '';
            app.CheckBox_4 = uicheckbox(app.GridLayout);
            app.CheckBox_4.ValueChangedFcn = createCallbackFcn(app, @CheckBox_4ValueChanged, true);
            app.CheckBox_4.Text = '';
            app.EditField = uieditfield(app.GridLayout, 'numeric');
            app.EditField2 = uieditfield(app.GridLayout, 'numeric');
            app.EditField3 = uieditfield(app.GridLayout, 'numeric');
            app.EditField4 = uieditfield(app.GridLayout, 'numeric');
            app.EditField5 = uieditfield(app.GridLayout, 'numeric');
            app.EditField6 = uieditfield(app.GridLayout, 'numeric');
            app.EditField7 = uieditfield(app.GridLayout, 'numeric');
            app.Power_200SliderLabel = uilabel(app.GridLayout);
            app.Power_200SliderLabel.Text = 'Power_200';
            app.Power_200Slider = uislider(app.GridLayout);
            app.Power_200Slider.ValueChangedFcn = createCallbackFcn(app, @Power_200SliderValueChanged, true);
            app.Power_300SliderLabel = uilabel(app.GridLayout);
            app.Power_300SliderLabel.Text = 'Power_300';
            app.Power_300Slider = uislider(app.GridLayout);
            app.Power_300Slider.ValueChangedFcn = createCallbackFcn(app, @Power_300SliderValueChanged, true);
            app.Power_400SliderLabel = uilabel(app.GridLayout);
            app.Power_400SliderLabel.Text = 'Power_400';
            app.Power_400Slider = uislider(app.GridLayout);
            app.Power_400Slider.ValueChangedFcn = createCallbackFcn(app, @Power_400SliderValueChanged, true);
            app.Power_500SliderLabel = uilabel(app.GridLayout);
            app.Power_500SliderLabel.Text = 'Power_500';
            app.Power_500Slider = uislider(app.GridLayout);
            app.Power_500Slider.ValueChangedFcn = createCallbackFcn(app, @Power_500SliderValueChanged, true);
            app.Voltage_200SliderLabel = uilabel(app.GridLayout);
            app.Voltage_200SliderLabel.Text = 'Voltage_200';
            app.Voltage_200Slider = uislider(app.GridLayout);
            app.Voltage_200Slider.ValueChangedFcn = createCallbackFcn(app, @Voltage_200SliderValueChanged, true);
            app.Voltage_300SliderLabel = uilabel(app.GridLayout);
            app.Voltage_300SliderLabel.Text = 'Voltage_300';
            app.Voltage_300Slider = uislider(app.GridLayout);
            app.Voltage_300Slider.ValueChangedFcn = createCallbackFcn(app, @Voltage_300SliderValueChanged, true);
            app.Voltage_300Slider.Value = 100;
            app.Voltage__400SliderLabel = uilabel(app.GridLayout);
            app.Voltage__400SliderLabel.Text = 'Voltage__400';
            app.Voltage__400Slider = uislider(app.GridLayout);
            app.Voltage__400Slider.ValueChangedFcn = createCallbackFcn(app, @Voltage__400SliderValueChanged, true);
            app.Voltage_500Slider = uislider(app.GridLayout);
            app.Voltage_500Slider.ValueChangedFcn = createCallbackFcn(app, @Voltage_500SliderValueChanged, true);
            app.Power_600SliderLabel = uilabel(app.GridLayout);
            app.Power_600SliderLabel.Text = 'Power_600';
            app.Power_600Slider = uislider(app.GridLayout);
            app.Power_600Slider.ValueChangedFcn = createCallbackFcn(app, @Power_600SliderValueChanged2, true);
            app.Power_700SliderLabel = uilabel(app.GridLayout);
            app.Power_700SliderLabel.Text = 'Power_700';
            app.Power_700Slider = uislider(app.GridLayout);
            app.Power_700Slider.ValueChangedFcn = createCallbackFcn(app, @Power_700SliderValueChanged, true);
            app.Voltage_600SliderLabel = uilabel(app.GridLayout);
            app.Voltage_600SliderLabel.Text = 'Voltage_600';
            app.Voltage_600Slider = uislider(app.GridLayout);
            app.Voltage_600Slider.ValueChangedFcn = createCallbackFcn(app, @Voltage_600SliderValueChanged, true);
            app.Voltage_700SliderLabel = uilabel(app.GridLayout);
            app.Voltage_700SliderLabel.Text = 'Voltage_700';
            app.Voltage_700Slider = uislider(app.GridLayout);
            app.Voltage_700Slider.ValueChangedFcn = createCallbackFcn(app, @Voltage_700SliderValueChanged, true);
            app.CheckBox_5 = uicheckbox(app.GridLayout);
            app.CheckBox_5.ValueChangedFcn = createCallbackFcn(app, @CheckBox_5ValueChanged, true);
            app.CheckBox_5.Text = '';
            app.Voltage_500SliderLabel = uilabel(app.GridLayout);
            app.Voltage_500SliderLabel.Text = 'Voltage_500';
            app.CheckBox_6 = uicheckbox(app.GridLayout);
            app.CheckBox_6.ValueChangedFcn = createCallbackFcn(app, @CheckBox_6ValueChanged, true);
            app.CheckBox_6.Text = '';
            app.CheckBox_11 = uicheckbox(app.GridLayout);
            app.CheckBox_11.ValueChangedFcn = createCallbackFcn(app, @CheckBox_11ValueChanged, true);
            app.CheckBox_11.Text = '';
            app.CheckBox_12 = uicheckbox(app.GridLayout);
            app.CheckBox_12.ValueChangedFcn = createCallbackFcn(app, @CheckBox_12ValueChanged, true);
            app.CheckBox_12.Text = '';
            app.CheckBox5 = uicheckbox(app.GridLayout);
            app.CheckBox5.ValueChangedFcn = createCallbackFcn(app, @CheckBox5ValueChanged, true);
            app.CheckBox5.Text = 'Check Box5';
            app.CheckBox6 = uicheckbox(app.GridLayout);
            app.CheckBox6.ValueChangedFcn = createCallbackFcn(app, @CheckBox6ValueChanged, true);
            app.CheckBox6.Text = 'Check Box6';
            app.CheckBox_7 = uicheckbox(app.GridLayout);
            app.CheckBox_7.ValueChangedFcn = createCallbackFcn(app, @CheckBox_7ValueChanged, true);
            app.CheckBox_7.Text = '';
            app.CheckBox_8 = uicheckbox(app.GridLayout);
            app.CheckBox_8.ValueChangedFcn = createCallbackFcn(app, @CheckBox_8ValueChanged, true);
            app.CheckBox_8.Text = '';
            app.CheckBox_9 = uicheckbox(app.GridLayout);
            app.CheckBox_9.ValueChangedFcn = createCallbackFcn(app, @CheckBox_9ValueChanged, true);
            app.CheckBox_9.Text = '';
            app.CheckBox_10 = uicheckbox(app.GridLayout);
            app.CheckBox_10.ValueChangedFcn = createCallbackFcn(app, @CheckBox_10ValueChanged, true);
            app.CheckBox_10.Text = '';
            app.Power_800SliderLabel = uilabel(app.GridLayout);
            app.Power_800SliderLabel.Text = 'Power_800';
            app.Power_800Slider = uislider(app.GridLayout);
            app.Power_800Slider.ValueChangedFcn = createCallbackFcn(app, @Power_800SliderValueChanged, true);
            app.Voltage_800SliderLabel = uilabel(app.GridLayout);
            app.Voltage_800SliderLabel.Text = 'Voltage_800';
            app.Voltage_800Slider = uislider(app.GridLayout);
            app.Voltage_800Slider.ValueChangedFcn = createCallbackFcn(app, @Voltage_800SliderValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = defects

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end