classdef HTS_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        GridLayout       matlab.ui.container.GridLayout
        Slider2_2        matlab.ui.control.Slider
        Slider_2Label_2  matlab.ui.control.Label
        Slider1_2        matlab.ui.control.Slider
        ResistorLabel    matlab.ui.control.Label
        Slider2          matlab.ui.control.Slider
        I_totLabel_2     matlab.ui.control.Label
        Slider1          matlab.ui.control.Slider
        I_totLabel       matlab.ui.control.Label
        UIAxes2_2        matlab.ui.control.UIAxes
        UIAxes2          matlab.ui.control.UIAxes
        UIAxes1_2        matlab.ui.control.UIAxes
        UIAxes1          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        slider1Value %= app.Slider1.Value; % no defect, current
        slider1_2Value %= app.Slider1_2.Value; % no defect, position
        slider2Value %= app.Slider2.Value; % defect, current
        slider2_2Value %= app.Slider2_2.Value; % defect, position
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.slider1Value = app.Slider1.Value; 
            app.slider1_2Value = app.Slider1_2.Value; 
            app.slider2Value = app.Slider2.Value; 
            app.slider2_2Value = app.Slider2_2.Value; 
        end

        % Value changed function: Slider1
        function Slider1ValueChanged(app, event)
            app.slider1Value = app.Slider1.Value; 
            I_total = app.slider1Value;
            run('GUI_totals_location.m');

            cla(app.UIAxes1, 'reset');
            hold(app.UIAxes1, 'on');
            for i = 1:5
                rowdata = [];
                for j = 1:length(converged)
                    data = converged{j}(i); % Extract data from the ith cell
                    rowdata(end + 1) = data;
                end
                plot(app.UIAxes1, Slice_set, rowdata, 'o-');    % Plot the data
                
            end
            legend(app.UIAxes1,'Tape 1', 'Tape 2', 'Tape 3', 'Tape 4', 'Tape 5');
            title(app.UIAxes1, 'No Defect, Variable Slice');
            hold(app.UIAxes1, 'off');
            
        end

        % Value changed function: Slider1_2
        function Slider1_2ValueChanged(app, event)
            app.slider1_2Value = app.Slider1_2.Value; 
            slice = round(app.slider1_2Value);
            run('GUI_totals_code.m');

            cla(app.UIAxes1_2, 'reset');
            hold(app.UIAxes1_2, 'on');
            for i = 1:5
                rowdata = [];
                for j = 1:length(converged)
                    data = converged{j}(i); % Extract data from the ith cell
                    rowdata(end + 1) = data;
                end
                plot(app.UIAxes1_2, I_total_set, rowdata, 'o-');    % Plot the data
                
            end
            legend(app.UIAxes1_2,'Tape 1', 'Tape 2', 'Tape 3', 'Tape 4', 'Tape 5');
            title(app.UIAxes1_2, 'No Defect, Variable Total Current');
            hold(app.UIAxes1_2, 'off');
        end

        % Value changed function: Slider2
        function Slider2ValueChanged(app, event)
            app.slider2Value = app.Slider2.Value; 
            I_total = app.slider2Value;
            run('GUI_totals_defect_location.m');

            cla(app.UIAxes2, 'reset');
            hold(app.UIAxes2, 'on');
            for i = 1:5
                rowdata = [];
                for j = 1:length(converged)
                    data = converged{j}(i); % Extract data from the ith cell
                    rowdata(end + 1) = data;
                end
                plot(app.UIAxes2, Slice_set, rowdata, 'o-');    % Plot the data
                
            end
            legend(app.UIAxes2,'Tape 1', 'Tape 2', 'Tape 3', 'Tape 4', 'Tape 5');
            title(app.UIAxes2, 'Defect, Variable Slice');
            hold(app.UIAxes2, 'off');
        end

        % Value changed function: Slider2_2
        function Slider2_2ValueChanged(app, event)
            app.slider2_2Value = app.Slider2_2.Value; 
            slice = round(app.slider2_2Value);
            run('GUI_totals_defect_code.m');

            cla(app.UIAxes2_2, 'reset');
            hold(app.UIAxes2_2, 'on');
            for i = 1:5
                rowdata = [];
                for j = 1:length(converged)
                    data = converged{j}(i); % Extract data from the ith cell
                    rowdata(end + 1) = data;
                end
                plot(app.UIAxes2_2, I_total_set, rowdata, 'o-');    % Plot the data
                
            end
            legend(app.UIAxes2_2,'Tape 1', 'Tape 2', 'Tape 3', 'Tape 4', 'Tape 5');
            title(app.UIAxes2_2, 'Defect, Variable Total Current');
            hold(app.UIAxes2_2, 'off');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes1 = uiaxes(app.GridLayout);
            title(app.UIAxes1, {'No Defect, Constant Total Current'; ''})
            xlabel(app.UIAxes1, 'X')
            ylabel(app.UIAxes1, 'Y')
            zlabel(app.UIAxes1, 'Z')
            app.UIAxes1_2 = uiaxes(app.GridLayout);
            title(app.UIAxes1_2, {'No Defect, Constant Position'; ''})
            xlabel(app.UIAxes1_2, 'X')
            ylabel(app.UIAxes1_2, 'Y')
            zlabel(app.UIAxes1_2, 'Z')
            app.UIAxes2 = uiaxes(app.GridLayout);
            title(app.UIAxes2, 'Defect, Constant Total Current')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2_2 = uiaxes(app.GridLayout);
            title(app.UIAxes2_2, {'Defect, Constant Position'; ''})
            xlabel(app.UIAxes2_2, 'X')
            ylabel(app.UIAxes2_2, 'Y')
            zlabel(app.UIAxes2_2, 'Z')
            app.I_totLabel = uilabel(app.GridLayout);
            app.I_totLabel.Text = 'I_tot';
            app.Slider1 = uislider(app.GridLayout);
            app.Slider1.Limits = [0 1000];
            app.Slider1.MajorTicks = [0 100 200 300 400 500 600 700 800 900 1000];
            app.Slider1.MajorTickLabels = {'0', '100', '200', '300', '400', '500', '600', '700', '800', '900', '1000'};
            app.Slider1.ValueChangedFcn = createCallbackFcn(app, @Slider1ValueChanged, true);
            app.Slider1.MinorTicks = [0 50 100 150 200 250 300 350 400 450 500 550 600 650 700 750 800 850 900 950 1000];
            app.Slider1.Value = 400;
            app.I_totLabel_2 = uilabel(app.GridLayout);
            app.I_totLabel_2.Text = 'I_tot';
            app.Slider2 = uislider(app.GridLayout);
            app.Slider2.Limits = [0 1000];
            app.Slider2.MajorTicks = [0 100 200 300 400 500 600 700 800 900 1000];
            app.Slider2.ValueChangedFcn = createCallbackFcn(app, @Slider2ValueChanged, true);
            app.Slider2.MinorTicks = [0 50 100 150 200 250 300 350 400 450 500 550 600 650 700 750 800 850 900 950 1000];
            app.ResistorLabel = uilabel(app.GridLayout);
            app.ResistorLabel.Text = 'Resistor';
            app.Slider1_2 = uislider(app.GridLayout);
            app.Slider1_2.Limits = [1 5];
            app.Slider1_2.MajorTicks = [1 2 3 4 5];
            app.Slider1_2.ValueChangedFcn = createCallbackFcn(app, @Slider1_2ValueChanged, true);
            app.Slider1_2.MinorTicks = [];
            app.Slider1_2.Value = 4.67106964451142;
            app.Slider_2Label_2 = uilabel(app.GridLayout);
            app.Slider_2Label_2.Text = 'Resistor';
            app.Slider2_2 = uislider(app.GridLayout);
            app.Slider2_2.Limits = [1 5];
            app.Slider2_2.MajorTicks = [1 2 3 4 5];
            app.Slider2_2.ValueChangedFcn = createCallbackFcn(app, @Slider2_2ValueChanged, true);
            app.Slider2_2.MinorTicks = [];
            app.Slider2_2.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HTS_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end